classdef LEDControl_PinOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LEDControl_PinOptionsUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        DropDown_LTC2465_8             matlab.ui.control.DropDown
        DropDown_LTC2465_7             matlab.ui.control.DropDown
        DropDown_LTC2465_6             matlab.ui.control.DropDown
        DropDown_LTC2465_5             matlab.ui.control.DropDown
        DropDown_LTC2465_4             matlab.ui.control.DropDown
        DropDown_LTC2465_3             matlab.ui.control.DropDown
        DropDown_LTC2465_2             matlab.ui.control.DropDown
        DropDown_LTC2465_1             matlab.ui.control.DropDown
        LabelLTC2465                   matlab.ui.control.Label
        DropDown_FM_8                  matlab.ui.control.DropDown
        DropDown_Power_8               matlab.ui.control.DropDown
        DropDown_FM_7                  matlab.ui.control.DropDown
        DropDown_Power_7               matlab.ui.control.DropDown
        DropDown_FM_6                  matlab.ui.control.DropDown
        DropDown_Power_6               matlab.ui.control.DropDown
        DropDown_FM_5                  matlab.ui.control.DropDown
        DropDown_Power_5               matlab.ui.control.DropDown
        DropDown_FM_4                  matlab.ui.control.DropDown
        DropDown_Power_4               matlab.ui.control.DropDown
        DropDown_FM_3                  matlab.ui.control.DropDown
        DropDown_Power_3               matlab.ui.control.DropDown
        DropDown_FM_2                  matlab.ui.control.DropDown
        DropDown_Power_2               matlab.ui.control.DropDown
        DropDown_PWM_8                 matlab.ui.control.DropDown
        DropDown_PWM_7                 matlab.ui.control.DropDown
        DropDown_PWM_6                 matlab.ui.control.DropDown
        DropDown_PWM_5                 matlab.ui.control.DropDown
        DropDown_PWM_4                 matlab.ui.control.DropDown
        DropDown_PWM_3                 matlab.ui.control.DropDown
        DropDown_PWM_2                 matlab.ui.control.DropDown
        DropDown_FM_1                  matlab.ui.control.DropDown
        DropDown_Power_1               matlab.ui.control.DropDown
        DropDown_PWM_1                 matlab.ui.control.DropDown
        LabelFM                        matlab.ui.control.Label
        LabelPower                     matlab.ui.control.Label
        LabelPWM                       matlab.ui.control.Label
        ButtonSave                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        LEDControl   % Main app object
        PWM_group
        FM_group
        power_group
        LTC2465_group
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, LEDControl, PWM_group, FM_group, power_group, LTC2465_group)
            app.LEDControl = LEDControl;
            app.PWM_group = PWM_group;
            app.FM_group = FM_group;
            app.power_group = power_group;
            app.LTC2465_group = LTC2465_group;
            
            
             for i = 1:8
                 app.(append("DropDown_PWM_",num2str(i))).Value = app.PWM_group(i,1);
                 app.(append("DropDown_FM_",num2str(i))).Value = app.FM_group(i,1);
                 app.(append("DropDown_Power_",num2str(i))).Value = app.power_group(i,1);
                 app.(append("DropDown_LTC2465_",num2str(i))).Value = app.LTC2465_group(i,1);
             end
        end

        % Button pushed function: ButtonSave
        function ButtonSavePushed(app, event)
            app.PWM_group = strings(8,1);
            app.FM_group = strings(8,1);
            app.power_group = strings(8,1);
            app.LTC2465_group = strings(8,1);
            
            for i = 1:8
                app.PWM_group(i,1) = app.(append("DropDown_PWM_",num2str(i))).Value;
                app.FM_group(i,1) = app.(append("DropDown_FM_",num2str(i))).Value;
                app.power_group(i,1) = app.(append("DropDown_Power_",num2str(i))).Value;
                app.LTC2465_group(i,1) = app.(append("DropDown_LTC2465_",num2str(i))).Value;
            end
            
            set_Pingroups(app.LEDControl, app.PWM_group, app.power_group, app.FM_group, app.LTC2465_group)
            delete(app)

        end

        % Close request function: LEDControl_PinOptionsUIFigure
        function LEDControl_PinOptionsUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LEDControl_PinOptionsUIFigure = uifigure('Visible', 'off');
            app.LEDControl_PinOptionsUIFigure.Name = 'LEDControl_PinOptions';
            app.LEDControl_PinOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @LEDControl_PinOptionsUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.LEDControl_PinOptionsUIFigure);
            app.ButtonSave = uibutton(app.GridLayout, 'push');
            app.ButtonSave.ButtonPushedFcn = createCallbackFcn(app, @ButtonSavePushed, true);
            app.ButtonSave.Text = 'Save';
            app.LabelPWM = uilabel(app.GridLayout);
            app.LabelPWM.Text = 'PWM Pins';
            app.LabelPower = uilabel(app.GridLayout);
            app.LabelPower.Text = {'Power Pins'; ''};
            app.LabelFM = uilabel(app.GridLayout);
            app.LabelFM.Text = 'FM Pins';
            app.DropDown_PWM_1 = uidropdown(app.GridLayout);
            app.DropDown_PWM_1.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.DropDown_PWM_1.Value = '4';
            app.DropDown_Power_1 = uidropdown(app.GridLayout);
            app.DropDown_Power_1.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_Power_1.Value = '23';
            app.DropDown_FM_1 = uidropdown(app.GridLayout);
            app.DropDown_FM_1.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_FM_1.Value = '22';
            app.DropDown_PWM_2 = uidropdown(app.GridLayout);
            app.DropDown_PWM_2.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.DropDown_PWM_2.Value = '5';
            app.DropDown_PWM_3 = uidropdown(app.GridLayout);
            app.DropDown_PWM_3.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.DropDown_PWM_3.Value = '6';
            app.DropDown_PWM_4 = uidropdown(app.GridLayout);
            app.DropDown_PWM_4.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.DropDown_PWM_4.Value = '7';
            app.DropDown_PWM_5 = uidropdown(app.GridLayout);
            app.DropDown_PWM_5.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.DropDown_PWM_5.Value = '8';
            app.DropDown_PWM_6 = uidropdown(app.GridLayout);
            app.DropDown_PWM_6.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.DropDown_PWM_6.Value = '9';
            app.DropDown_PWM_7 = uidropdown(app.GridLayout);
            app.DropDown_PWM_7.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.DropDown_PWM_7.Value = '10';
            app.DropDown_PWM_8 = uidropdown(app.GridLayout);
            app.DropDown_PWM_8.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.DropDown_PWM_8.Value = '11';
            app.DropDown_Power_2 = uidropdown(app.GridLayout);
            app.DropDown_Power_2.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_Power_2.Value = '25';
            app.DropDown_FM_2 = uidropdown(app.GridLayout);
            app.DropDown_FM_2.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_FM_2.Value = '24';
            app.DropDown_Power_3 = uidropdown(app.GridLayout);
            app.DropDown_Power_3.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_Power_3.Value = '27';
            app.DropDown_FM_3 = uidropdown(app.GridLayout);
            app.DropDown_FM_3.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_FM_3.Value = '26';
            app.DropDown_Power_4 = uidropdown(app.GridLayout);
            app.DropDown_Power_4.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_Power_4.Value = '29';
            app.DropDown_FM_4 = uidropdown(app.GridLayout);
            app.DropDown_FM_4.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_FM_4.Value = '28';
            app.DropDown_Power_5 = uidropdown(app.GridLayout);
            app.DropDown_Power_5.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_Power_5.Value = '31';
            app.DropDown_FM_5 = uidropdown(app.GridLayout);
            app.DropDown_FM_5.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_FM_5.Value = '30';
            app.DropDown_Power_6 = uidropdown(app.GridLayout);
            app.DropDown_Power_6.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_Power_6.Value = '33';
            app.DropDown_FM_6 = uidropdown(app.GridLayout);
            app.DropDown_FM_6.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_FM_6.Value = '32';
            app.DropDown_Power_7 = uidropdown(app.GridLayout);
            app.DropDown_Power_7.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_Power_7.Value = '35';
            app.DropDown_FM_7 = uidropdown(app.GridLayout);
            app.DropDown_FM_7.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_FM_7.Value = '34';
            app.DropDown_Power_8 = uidropdown(app.GridLayout);
            app.DropDown_Power_8.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_Power_8.Value = '37';
            app.DropDown_FM_8 = uidropdown(app.GridLayout);
            app.DropDown_FM_8.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_FM_8.Value = '36';
            app.LabelLTC2465 = uilabel(app.GridLayout);
            app.LabelLTC2465.Text = {'LTC2465 Pins'; ''};
            app.DropDown_LTC2465_1 = uidropdown(app.GridLayout);
            app.DropDown_LTC2465_1.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_LTC2465_1.Value = '47';
            app.DropDown_LTC2465_2 = uidropdown(app.GridLayout);
            app.DropDown_LTC2465_2.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_LTC2465_2.Value = '49';
            app.DropDown_LTC2465_3 = uidropdown(app.GridLayout);
            app.DropDown_LTC2465_3.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_LTC2465_3.Value = '51';
            app.DropDown_LTC2465_4 = uidropdown(app.GridLayout);
            app.DropDown_LTC2465_4.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_LTC2465_4.Value = '53';
            app.DropDown_LTC2465_5 = uidropdown(app.GridLayout);
            app.DropDown_LTC2465_5.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_LTC2465_5.Value = '47';
            app.DropDown_LTC2465_6 = uidropdown(app.GridLayout);
            app.DropDown_LTC2465_6.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_LTC2465_6.Value = '47';
            app.DropDown_LTC2465_7 = uidropdown(app.GridLayout);
            app.DropDown_LTC2465_7.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_LTC2465_7.Value = '47';
            app.DropDown_LTC2465_8 = uidropdown(app.GridLayout);
            app.DropDown_LTC2465_8.Items = {'21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53'};
            app.DropDown_LTC2465_8.Value = '47';
            % Show the figure after all components are created
            app.LEDControl_PinOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LEDControl_PinOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LEDControl_PinOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LEDControl_PinOptionsUIFigure)
        end
    end
end